classdef TOST_indep_raw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MeanEditFieldLabel     matlab.ui.control.Label
        mu1                    matlab.ui.control.NumericEditField
        Table2                 matlab.ui.control.Table
        Table1                 matlab.ui.control.Table
        TOSTLabel              matlab.ui.control.Label
        SDEditFieldLabel       matlab.ui.control.Label
        sd1                    matlab.ui.control.NumericEditField
        Group1Label            matlab.ui.control.Label
        nEditFieldLabel        matlab.ui.control.Label
        n1                     matlab.ui.control.NumericEditField
        Group2Label            matlab.ui.control.Label
        MeanEditField_2Label   matlab.ui.control.Label
        mu2                    matlab.ui.control.NumericEditField
        SDEditField_2Label     matlab.ui.control.Label
        sd2                    matlab.ui.control.NumericEditField
        nEditField_2Label      matlab.ui.control.Label
        n2                     matlab.ui.control.NumericEditField
        EquivalenceboundLabel  matlab.ui.control.Label
        Bound                  matlab.ui.control.NumericEditField
        CalculateButton        matlab.ui.control.Button
        ExtraTable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        tstat = @(mu1, mu2, delta, var1, var2, n1, n2) (mu1 - mu2 - delta) / sqrt((var1/n1) + (var2/n2)); % t-statistic 
        dfw = @(var1, n1, var2, n2) ((var1/n1) + (var2/n2))^2 / (((var1/n1)^2/(n1-1)) + ((var2/n2)^2/(n2 - 1))); % Welch's degrees of freedom 
        tdist1T = @(t,df) (1-(1-betainc(df/(df+t^2),df/2,0.5)))/2;              % 1-tailed t-distribution
        ES = @(mu1, mu2, pooledstd) (mu1 - mu2) / pooledstd; % effect size 
        cohenD = @(delta, n1, var1, n2, var2) delta / (sqrt((((n1 - 1)*var1 + (n2 - 1)*var2)) / (n1 + n2 - 2)));   % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            TU = app.tstat(app.mu1.Value, app.mu2.Value, app.Bound.Value, app.sd1.Value^2, app.sd2.Value^2, app.n1.Value, app.n2.Value);
            TL = app.tstat(app.mu1.Value, app.mu2.Value, -app.Bound.Value, app.sd1.Value^2, app.sd2.Value^2, app.n1.Value, app.n2.Value);
            DF = app.dfw(app.sd1.Value^2, app.n1.Value, app.sd2.Value^2, app.n2.Value);
            pL = app.tdist1T(TL, DF);
            pU = app.tdist1T(TU, DF);
            effectsize = app.ES(app.mu1.Value, app.mu2.Value, (app.sd1.Value^2 + app.sd2.Value^2)/2);
            cohenD = app.cohenD(app.Bound.Value, app.n1.Value, app.sd1.Value^2, app.n2.Value, app.sd2.Value^2); 
            
            app.Table1.Data = [TL; DF; pL];
            app.Table2.Data = [TU; DF; pU];
            app.ExtraTable.Data = [effectsize, cohenD]; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.mu1 = uieditfield(app.UIFigure, 'numeric');
            app.Table2 = uitable(app.UIFigure);
            app.Table2.ColumnName = '';
            app.Table2.RowName = {'t'; 'df'; 'p'};
            app.Table1 = uitable(app.UIFigure);
            app.Table1.ColumnName = '';
            app.Table1.RowName = {'t'; 'df'; 'p'};
            app.TOSTLabel = uilabel(app.UIFigure);
            app.TOSTLabel.Text = 'TOST';
            app.SDEditFieldLabel = uilabel(app.UIFigure);
            app.SDEditFieldLabel.Text = 'SD';
            app.sd1 = uieditfield(app.UIFigure, 'numeric');
            app.Group1Label = uilabel(app.UIFigure);
            app.Group1Label.Text = 'Group 1';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.n1 = uieditfield(app.UIFigure, 'numeric');
            app.Group2Label = uilabel(app.UIFigure);
            app.Group2Label.Text = 'Group 2';
            app.MeanEditField_2Label = uilabel(app.UIFigure);
            app.MeanEditField_2Label.Text = 'Mean';
            app.mu2 = uieditfield(app.UIFigure, 'numeric');
            app.SDEditField_2Label = uilabel(app.UIFigure);
            app.SDEditField_2Label.Text = 'SD';
            app.sd2 = uieditfield(app.UIFigure, 'numeric');
            app.nEditField_2Label = uilabel(app.UIFigure);
            app.nEditField_2Label.Text = 'n';
            app.n2 = uieditfield(app.UIFigure, 'numeric');
            app.EquivalenceboundLabel = uilabel(app.UIFigure);
            app.EquivalenceboundLabel.Text = 'Equivalence bound';
            app.Bound = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ExtraTable = uitable(app.UIFigure);
            app.ExtraTable.ColumnName = {'Effect Size'; 'Cohen''s d'};
            app.ExtraTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TOST_indep_raw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end