classdef PF_comp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        alphaSliderLabel   matlab.ui.control.Label
        alphaSlider        matlab.ui.control.Slider
        betaSliderLabel    matlab.ui.control.Label
        betaSlider         matlab.ui.control.Slider
        lambdaSliderLabel  matlab.ui.control.Label
        lambdaSlider       matlab.ui.control.Slider
        gammaSliderLabel   matlab.ui.control.Label
        gammaSlider        matlab.ui.control.Slider
        Beta_0SliderLabel  matlab.ui.control.Label
        Beta_0Slider       matlab.ui.control.Slider
        Beta_1SliderLabel  matlab.ui.control.Label
        Beta_1Slider       matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % 4 parameter psychometric function with logistic function as nested function 
        psi = @(x,xdata) x(4) + (1 - x(3) - x(4)) * (1 ./ (1 + exp(-x(2) * (xdata - x(1))))); % alpha, beta, lambda, gamma % Description
        intensity = -0.4:0.01:0.4; % x-values 
        params % parameters 
        gamma_opts = [0, 0.25, 0.33, 0.5];
        two_params % 2 parameter model 
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % ylimit 
            app.UIAxes.YLim = [0,1];
            app.UIAxes2.YLim = [0,1];
            % parameters
            app.params = [app.alphaSlider.Value, ...
                app.betaSlider.Value, ...
                app.lambdaSlider.Value, ...
                app.gammaSlider.Value];
            app.two_params = [app.Beta_0Slider.Value, ...
                app.Beta_1Slider.Value]; 
            % create and plot function 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
            fplot(app.UIAxes2, @(x) normcdf(x, app.two_params(1), app.two_params(2)), [-0.4 0.4], 'linewidth', 2); 
        end

        % Value changing function: lambdaSlider
        function lambdaSliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameters
            app.params(3) = changingValue; 
            % replot 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Value changing function: betaSlider
        function betaSliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameters
            app.params(2) = changingValue; 
            % replot 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Value changing function: alphaSlider
        function alphaSliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameters
            app.params(1) = changingValue; 
            % replot 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameters
            app.params(4) = changingValue; 
            % replot 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Value changing function: Beta_1Slider
        function Beta_1SliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameter 
            app.two_params(2) = changingValue;
            % replot 
            fplot(app.UIAxes2, @(x) normcdf(x, app.two_params(1), app.two_params(2)), [-0.4 0.4], 'linewidth', 2); 
        end

        % Value changing function: Beta_0Slider
        function Beta_0SliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameter 
            app.two_params(1) = changingValue;
            fplot(app.UIAxes2, @(x) normcdf(x, app.two_params(1), app.two_params(2)), [-0.4 0.4], 'linewidth', 2); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [-0.4 0.4];
            app.alphaSlider.ValueChangingFcn = createCallbackFcn(app, @alphaSliderValueChanging, true);
            app.betaSliderLabel = uilabel(app.UIFigure);
            app.betaSliderLabel.Text = 'beta';
            app.betaSlider = uislider(app.UIFigure);
            app.betaSlider.Limits = [6 40];
            app.betaSlider.ValueChangingFcn = createCallbackFcn(app, @betaSliderValueChanging, true);
            app.betaSlider.Value = 26;
            app.lambdaSliderLabel = uilabel(app.UIFigure);
            app.lambdaSliderLabel.Text = 'lambda';
            app.lambdaSlider = uislider(app.UIFigure);
            app.lambdaSlider.Limits = [0 0.5];
            app.lambdaSlider.ValueChangingFcn = createCallbackFcn(app, @lambdaSliderValueChanging, true);
            app.gammaSliderLabel = uilabel(app.UIFigure);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.UIFigure);
            app.gammaSlider.Limits = [0 0.5];
            app.gammaSlider.MajorTicks = [0 0.25 0.33 0.5];
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.gammaSlider.MinorTicks = [];
            app.Beta_0SliderLabel = uilabel(app.UIFigure);
            app.Beta_0SliderLabel.Text = 'Beta_0';
            app.Beta_0Slider = uislider(app.UIFigure);
            app.Beta_0Slider.Limits = [-0.4 0.4];
            app.Beta_0Slider.ValueChangingFcn = createCallbackFcn(app, @Beta_0SliderValueChanging, true);
            app.Beta_1SliderLabel = uilabel(app.UIFigure);
            app.Beta_1SliderLabel.Text = 'Beta_1';
            app.Beta_1Slider = uislider(app.UIFigure);
            app.Beta_1Slider.Limits = [0 0.5];
            app.Beta_1Slider.ValueChangingFcn = createCallbackFcn(app, @Beta_1SliderValueChanging, true);
            app.Beta_1Slider.Value = 0.09;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '4 parameter model')
            xlabel(app.UIAxes, 'Stimulus')
            ylabel(app.UIAxes, 'Probability correct')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '2 parameter model')
            xlabel(app.UIAxes2, 'Stimulus')
            ylabel(app.UIAxes2, 'Probability Correct')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PF_comp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end