classdef eye_illusions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Trial1Faster  matlab.ui.control.Button
        Trial2Faster  matlab.ui.control.Button
        FasterLabel   matlab.ui.control.Label
        GoLabel       matlab.ui.control.Label
        Trial1Go      matlab.ui.control.Button
        Trial2Go      matlab.ui.control.Button
        ResetButton   matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        trial_order = ["p", "s"]; % trial order [pursuit, stationary]
        speeds = ['f', 's']; % speed [fast, slow] 
    end
    
    methods (Access = private)
        
        function rand_order(app)
            % randomize the order of the trials 
            app.trial_order = app.trial_order(randperm(length(app.trial_order))); 
            % randomize order of which trial is faster 
            app.speeds = app.speeds(randperm(length(app.speeds))); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % randomize trial order 
            rand_order(app); 
            % set axis limits 
            ylim(app.UIAxes, [0 1])
            xlim(app.UIAxes, [0 1]); 
        end

        % Button pushed function: Trial1Go
        function Trial1GoPushed(app, event)
            % clear axes
            cla(app.UIAxes)
            % find type of trial 
            trial_type = app.trial_order(1);
            if strcmpi(app.speeds(1), 'f')
                pause_time = 0.001;
            elseif strcmpi(app.speeds(1), 's')
                pause_time = 0.005; 
            end
            
            % plot 
            if strcmpi(trial_type, 'p') 
                % plot point at left most location 
                plt = scatter(app.UIAxes, 0, 0.5, 15, 'k', 'filled');
                pause(1); 
                for i = 1:100
                    plt.XData = plt.XData+0.01; % move right 
                    pause(pause_time); % pause 0.01s 
                end
            elseif strcmpi(trial_type, 's')
                % plot point at left most location 
                plot(app.UIAxes, 0.5, 0.5, 'ro', 'markersize', 5);
                hold(app.UIAxes, 'on')
                pause(1); 
                plt = scatter(app.UIAxes, 0, 0.5, 15, 'k', 'filled');
                for i = 1:100
                    plt.XData = plt.XData+0.01; % move right 
                    pause(pause_time); % pause 0.01s 
                end
            end
            % disable 
            app.Trial1Go.Enable = 0;
        end

        % Button pushed function: Trial2Go
        function Trial2GoButtonPushed(app, event)
            % clear axes
            cla(app.UIAxes)
            % find type of trial 
            trial_type = app.trial_order(2);
            if strcmpi(app.speeds(2), 'f')
                pause_time = 0.001;
            elseif strcmpi(app.speeds(2), 's')
                pause_time = 0.005; 
            end
            
            % plot 
            if strcmpi(trial_type, 'p') 
                % plot point at left most location 
                plt = scatter(app.UIAxes, 0, 0.5, 15, 'k', 'filled');
                pause(1); 
                for i = 1:100
                    plt.XData = plt.XData+0.01; % move right 
                    pause(pause_time); % pause 0.01s 
                end
            elseif strcmpi(trial_type, 's')
                % plot point at left most location 
                plot(app.UIAxes, 0.5, 0.5, 'ro', 'markersize', 5);
                hold(app.UIAxes, 'on')
                pause(1); 
                plt = scatter(app.UIAxes, 0, 0.5, 15, 'k', 'filled');
                for i = 1:100
                    plt.XData = plt.XData+0.01; % move right 
                    pause(pause_time); % pause 0.01s 
                end
            end
            % disable 
            app.Trial2Go.Enable = 0; 
        end

        % Button pushed function: Trial1Faster
        function Trial1FasterButtonPushed(app, event)
            if strcmpi(app.speeds(1), 'f') % correct 
                app.Trial1Faster.BackgroundColor = 'g'; 
            else % wrong 
                app.Trial1Faster.BackgroundColor = 'r';
            end
        end

        % Button pushed function: Trial2Faster
        function Trial2FasterButtonPushed(app, event)
            if strcmpi(app.speeds(2), 'f') % correct 
                app.Trial2Faster.BackgroundColor = 'g'; 
            else % wrong 
                app.Trial2Faster.BackgroundColor = 'r';
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % clear axes 
            cla(app.UIAxes);
            % enable trial buttons 
            app.Trial1Go.Enable = 1;
            app.Trial2Go.Enable = 1;
            % uncolorize faster buttons 
            app.Trial1Faster.BackgroundColor = [0.96,0.96,0.96];
            app.Trial2Faster.BackgroundColor = [0.96,0.96,0.96]; 
            % re-ramdomize 
            rand_order(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Trial1Faster = uibutton(app.UIFigure, 'push');
            app.Trial1Faster.ButtonPushedFcn = createCallbackFcn(app, @Trial1FasterButtonPushed, true);
            app.Trial1Faster.Text = 'Trial 1';
            app.Trial2Faster = uibutton(app.UIFigure, 'push');
            app.Trial2Faster.ButtonPushedFcn = createCallbackFcn(app, @Trial2FasterButtonPushed, true);
            app.Trial2Faster.Text = 'Trial 2';
            app.FasterLabel = uilabel(app.UIFigure);
            app.FasterLabel.Text = 'Faster?';
            app.GoLabel = uilabel(app.UIFigure);
            app.GoLabel.Text = 'Go';
            app.Trial1Go = uibutton(app.UIFigure, 'push');
            app.Trial1Go.ButtonPushedFcn = createCallbackFcn(app, @Trial1GoPushed, true);
            app.Trial1Go.Text = 'Trial 1';
            app.Trial2Go = uibutton(app.UIFigure, 'push');
            app.Trial2Go.ButtonPushedFcn = createCallbackFcn(app, @Trial2GoButtonPushed, true);
            app.Trial2Go.Text = 'Trial 2';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eye_illusions

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end