classdef customSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StartTargetSpinnerLabel     matlab.ui.control.Label
        StartTargetSpinner          matlab.ui.control.Spinner
        EndTargetSpinnerLabel       matlab.ui.control.Label
        EndTargetSpinner            matlab.ui.control.Spinner
        GoButton                    matlab.ui.control.StateButton
        ControlEditFieldLabel       matlab.ui.control.Label
        Control_RL                  matlab.ui.control.NumericEditField
        ResponseLatencyLabel        matlab.ui.control.Label
        LALabel                     matlab.ui.control.Label
        LA_RL                       matlab.ui.control.NumericEditField
        RALabel                     matlab.ui.control.Label
        RA_RL                       matlab.ui.control.NumericEditField
        PeakSpeedRatioLabel         matlab.ui.control.Label
        ControlEditFieldLabel_2     matlab.ui.control.Label
        Control_PSR                 matlab.ui.control.NumericEditField
        LALabel_2                   matlab.ui.control.Label
        LA_PSR                      matlab.ui.control.NumericEditField
        RALabel_2                   matlab.ui.control.Label
        RA_PSR                      matlab.ui.control.NumericEditField
        InitialDirectionErrorLabel  matlab.ui.control.Label
        ControlEditFieldLabel_3     matlab.ui.control.Label
        Control_IDE                 matlab.ui.control.NumericEditField
        LALabel_3                   matlab.ui.control.Label
        LA_IDE                      matlab.ui.control.NumericEditField
        RALabel_3                   matlab.ui.control.Label
        RA_IDE                      matlab.ui.control.NumericEditField
        PathLengthRatioLabel        matlab.ui.control.Label
        ControlEditFieldLabel_4     matlab.ui.control.Label
        Control_PLR                 matlab.ui.control.NumericEditField
        LALabel_4                   matlab.ui.control.Label
        LA_PLR                      matlab.ui.control.NumericEditField
        RALabel_4                   matlab.ui.control.Label
        RA_PLR                      matlab.ui.control.NumericEditField
        Position                    matlab.ui.control.UIAxes
        Speed                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        rotation_matrix = [nan, 120, 60; 300, nan, 0; 240, 180, nan]; % degress for rotating
        robot_peak = 0.28; % robot peak speed 
        % Set up plotting
        robot_color = 'b'; 
        control_color = 'r'; 
        LA_color = 'm';
        RA_color = 'c'; 
        % targets 
        ATBA_targs;
        nATBA_targs; 
        % positions
        robX;
        robY;
        controlX;
        controlY;
        LAX; 
        LAY;
        RAX;
        RAY;
        % velocities
        robot_vel;
        control_vel;
        LA_vel;
        RA_vel; 
    end
    
    methods (Access = public)
        
        function createTargs(app)
            % trig
            home = [-10,-10]; 
            dists = 20; 
%             theta = acosd((dists^2 + dists^2 - dists^2) / (2 * dists * dists)); 
            left = [dists * cosd(120) + home(1), dists * sind(120) + home(2)];
            right = [dists * cosd(60) + home(1), dists * sind(120) + home(2)]; 
            
            % combine 
            app.ATBA_targs = [home; left; right]; 
            % displace 
            app.ATBA_targs(:,1) = app.ATBA_targs(:,1) - 5; 
            % mirror
            app.nATBA_targs = [-app.ATBA_targs(:,1), app.ATBA_targs(:,2)]; 
            
            % visual confirmation 
            plot(app.Position, app.ATBA_targs(:,1), app.ATBA_targs(:,2), 'o', 'markersize', 10, 'color', 'w', 'linewidth', 2)
            hold(app.Position, 'on')
            plot(app.Position, app.nATBA_targs(:,1), app.nATBA_targs(:,2), 'o', 'markersize', 10, 'color', 'w', 'linewidth', 2)
            axis(app.Position, 'equal')
            axis(app.Position, 'padded')
            plot(app.Position, [0 0], get(app.Position, 'YLim'), 'w--');
            kBackground(app, app.Position); 
             
        end
        
        function createReaches(app)
            % so people don't click again while running
            app.GoButton.BackgroundColor = 'r';
            
            % clear old children
            if length(app.Position.Children) > 4
                % clear old ones 
                delete(app.Position.Children(1:end-4));
                delete(app.Speed.Children); 
            end
            
            start_targ = app.StartTargetSpinner.Value;
            end_targ = app.EndTargetSpinner.Value; 
            % robot 
            [app.robX, app.robY, time] = reach_wKIN(app, app.ATBA_targs(start_targ,:), app.ATBA_targs(end_targ,:), app.robot_peak, [], [], [], [], [], []);
            app.robot_vel = reachXY2vel(app, app.robX, app.robY, time);
            
            % control
            [app.controlX, app.controlY, time] = reach_wKIN(app, app.ATBA_targs(start_targ,:), app.ATBA_targs(end_targ,:), app.robot_peak, ...
                app.Control_RL.Value, app.Control_PSR.Value, app.Control_PLR.Value, app.Control_IDE.Value, app.rotation_matrix, [start_targ end_targ]); 
            app.control_vel = reachXY2vel(app, app.controlX, app.controlY, time); 
            
            % LA
            [app.LAX, app.LAY, time] = reach_wKIN(app, app.ATBA_targs(start_targ,:), app.ATBA_targs(end_targ,:), app.robot_peak, ...
                app.LA_RL.Value, app.LA_PSR.Value, app.LA_PLR.Value, app.LA_IDE.Value, app.rotation_matrix, [start_targ end_targ]); 
            app.LA_vel = reachXY2vel(app, app.LAX, app.LAY, time); 
            
            % RA
            [app.RAX, app.RAY, time] = reach_wKIN(app, app.ATBA_targs(start_targ,:), app.ATBA_targs(end_targ,:), app.robot_peak, ...
                app.RA_RL.Value, app.RA_PSR.Value, app.RA_PLR.Value, app.RA_IDE.Value, app.rotation_matrix, [start_targ end_targ]); 
            app.RA_vel = reachXY2vel(app, app.RAX, app.RAY, time); 
            
            % visualize 
            plot(app.Position, nan, nan, 'bo', 'linewidth', 2, 'markersize', 8); % ATBA hand 
            % nATBA hand 
            plot(app.Position, nan, nan, 'o', 'LineWidth', 2, 'markersize', 8, 'color', app.control_color); % control 
            plot(app.Position, nan, nan, 'o', 'LineWidth', 2, 'markersize', 8, 'color', app.LA_color); % LA 
            plot(app.Position, nan, nan, 'o', 'LineWidth', 2, 'markersize', 8, 'color', app.RA_color); % RA 
 
            plot(app.Speed, 1:length(app.robot_vel), app.robot_vel, 'b-', 'linewidth', 2, 'displayname', 'ATBA'); % ATBA velocity 
            hold(app.Speed, 'on') 
            % nATBA velocity
            plot(app.Speed, 1:length(app.control_vel), app.control_vel, '-', 'linewidth', 2, 'color', app.control_color, 'displayname', 'Control'); % control
            plot(app.Speed, 1:length(app.LA_vel), app.LA_vel, '-', 'linewidth', 2, 'color', app.LA_color, 'displayname', 'LA'); % LA
            plot(app.Speed, 1:length(app.RA_vel), app.RA_vel, '-', 'linewidth', 2, 'color', app.RA_color, 'displayname', 'RA'); % RA
            plot(app.Speed, [nan nan], [nan nan], 'k--', 'displayname', 'time'); % time line
            legend(app.Speed, 'location', 'northeast')
            
        end
        
        function hand_vel = reachXY2vel(app, reachX, reachY, time)
            XVel = derivative(app, reachX) ./ derivative(app, time); 
            YVel = derivative(app, reachY) ./ derivative(app, time); 
            hand_vel = sqrt(XVel.^2 + YVel.^2); 
        end
        
        function kBackground(app, ax)
            % INPUT:
            % ax: axes to give a black background to 
            
            % get limits 
            xlim = get(ax, 'XLim');
            ylim = get(ax, 'YLim');
            
            % give balck background 
            patch(ax, [xlim(1) xlim(2) xlim(2) xlim(1)], [ylim(1) ylim(1) ylim(2) ylim(2)], 'k');
            
            % make last child so other visuals will show 
            ax.Children = [ax.Children(2:end);ax.Children(1)];

        end
        
        function [reachX, reachY, time] = reach_wKIN(app, start_targ, end_targ, peak_speed, RL, PSR, PLR, IDE, rotation_matrix, targ_nums)
            % INPUTS:
            % start_targ: start target [x,y]
            % end_targ: end targer [x,y]
            % peak_speed: peak speed (m/s)
            % RL: response latency (ms)
            % PSR: peak speed ratio 
            % PLR: path length ratio 
            % IDE: initial direction error (deg)
            % rotation_matrix: rotation degree matrix 
            % targ_nums: target numbers 
            
            % OUTPUT:
            % reachXY: reach X and Y cartesian coordinates 
            % time: time vector (ms sampling)
            
            if isempty(RL) && isempty(PSR) && isempty(PLR) && isempty(IDE) % robot reach 
                
                % duration 
                duration = round(s_to_ms(app, dur_calc_KINARM(app, start_targ, end_targ, peak_speed))); 
                % time 
                time = 1:1:duration; 
                % reach 
                [reachX, reachY] = T1reachT2(app, start_targ, end_targ, duration); 
                
            else % mirror match reach 
                
                % incorporate PSR 
                peak = peak_speed * PSR; 
                % incorporate PLR and IDE 
                distance = 20 * PLR; % 20 cm known distance 
                deg = rotation_matrix(targ_nums(1), targ_nums(2)); 
                if rand > 0.5
                    deg = deg + IDE;
                else
                    deg = deg - IDE;
                end
                end_targ(1) = distance * cosd(deg) + start_targ(1);
                end_targ(2) = distance * sind(deg) + start_targ(2); 
                % duration 
                duration = round(s_to_ms(app, dur_calc_KINARM(app, start_targ, end_targ, peak))); 
                % time 
                time = 1:1:duration+round(RL);
                [reachX, reachY] = T1reachT2(app, start_targ, end_targ, duration); 
                % incorporate RL
                reachX = [ones([1 round(RL)])*start_targ(1), reachX];
                reachY = [ones([1 round(RL)])*start_targ(2), reachY];
                
            end

        end
    end
    
    methods (Access = private)
        
        function duration = dur_calc_KINARM(app, start_targ, end_targ, desired_peak)

            % INPUTS:
            % start_targ: [1x2] x,y location for start target (cm)
            % end_targ: [1,2] x,y location for end target (cm)
            % desired_peak: desired peak velocity (m/s)
            
            % OUTPUT:
            % duration: movement time (s) needed to create a bell shaped velocity curve
            % with peak at desired peak 
            
            % IC's
            h = 1e-4; % step 
            % Start target breakdown (converted to m)
            x_i = start_targ(1) / 100; 
            y_i = start_targ(2) / 100;
            % End target breakdown (converted to m)
            x_f = end_targ(1) / 100;
            y_f = end_targ(2) / 100;
            % exagerate desired peak 
            desired_peak = round(desired_peak,4);
            
            % Determine what time to start at 
            guess = better_guess_T(app, x_i, y_i, x_f, y_f, h, desired_peak);
            % find difference between guess peaks and desired peak 
            guess(:,3) = abs(guess(:,2) - desired_peak);
            % find smallest difference 
            [~,r] = min(guess(:,3));
            
            % Pick end time for small steps 
            if r == 1 % if it's the first, increase TT 
                TT = 25;
            else % otherwise, index +1 to be safe (because of backwards iterations)
                r = r-1;
                TT = guess(r,1);
            end
            
            % TT = 1.5; % for testing 
            % time vector to check (s)
            T_full = TT:-h:0;
            
            % Test options
            for j = 1:size(T_full,2)
                
                % Set reach end time for this iteration (s)
                duration = T_full(j);
                
                % Create a time vector (ms)
                time = 0:h:duration;
            
                % Calculations (from VirtualTrajectory Stateflow) 
                % sigmoid shape (i.e. derivative result in bell shape)
                trajectory_shape = time./duration-sin(2*pi.*time/duration)./(2*pi); 
                % positions 
                virt_pos(:,1) = x_i + (x_f - x_i)* trajectory_shape;
                virt_pos(:,2) = y_i + (y_f - y_i)* trajectory_shape;
                % velocity/speed 
                virt_vel(:,1) = diff(virt_pos(:,1))./diff(time');
                virt_vel(:,2) = diff(virt_pos(:,2))./diff(time');
                % hand velocity 
                hand_vel = sqrt((virt_vel(:,1)).^2 + (virt_vel(:,2).^2));
                
                % Stop when time is found 
                if max(hand_vel(:,1)) == desired_peak 
                    % Plot
            %         figure;
            %         plot(hand_vel, 'b-', 'linewidth', 1.5)
            %         xlabel('Time (ms)')
            %         ylabel('Velocity (m/s)')
            %         title(sprintf('Exact match: Peak: %.7f m/s; Duration: %.4f s', max(hand_vel), duration))
            %         hold on 
            %         plot([1 length(hand_vel)], [max(hand_vel) max(hand_vel)], 'k--')
                    return
                elseif max(hand_vel(:,1)) >= desired_peak 
                    % Plot 
            %         figure;
            %         plot(hand_vel, 'b-', 'linewidth', 1.5)
            %         xlabel('Time (ms)')
            %         ylabel('Velocity (m/s)')
            %         title(sprintf('Above match: Peak: %.7f m/s; Duration %.4f s', max(hand_vel), duration))
            %         hold on 
            %         plot([1 length(hand_vel)], [max(hand_vel(:,1)) max(hand_vel(:,1))], 'k--')
                    return
                end
                
                clearvars trajectory_shape virt_pos virt_vel hand_vel
                        
            end

        end
        
        function guess = better_guess_T(app, x_i, y_i, x_f, y_f, h, desired_peak)

        % Test large steps to get a better estimate of where to start 
        
        % dynamic step for guesses (optimized as of [9.15.20])
        if desired_peak <= 0.1
            step = -0.14;
        elseif desired_peak > 0.1 && desired_peak <= 0.2
            step = -0.13;
        elseif desired_peak > 0.2 && desired_peak <= 0.3
            step = -0.09;
        elseif desired_peak > 0.3 && desired_peak <= 0.4
            step = -0.1;
        elseif desired_peak > 0.4
            step = -0.19;
        end
        
        % large steps to test 
        T_guesses = 20:step:1;
        guess = nan(length(T_guesses),2);
            
            for i = 1:length(T_guesses)
                
               duration = T_guesses(i); % choose end time 
              
               % Set reach end time for this iteration (s)
               guess(i,1) = duration;
                
               % Create a time vector (ms)
               time = 0:h:duration;
                
               % Calculations (from VirtualTrajectory Stateflow) 
               % sigmoid shape (i.e. derivative result in bell shape)
               trajectory_shape = time./duration-sin(2*pi.*time/duration)./(2*pi); 
               % positions 
               virt_pos(:,1) = x_i + (x_f - x_i)* trajectory_shape;
               virt_pos(:,2) = y_i + (y_f - y_i)* trajectory_shape;
               % velocity/speed 
               virt_vel(:,1) = diff(virt_pos(:,1))./diff(time');
               virt_vel(:,2) = diff(virt_pos(:,2))./diff(time');
               % hand velocity 
               hand_vel = sqrt((virt_vel(:,1)).^2 + (virt_vel(:,2).^2));
        
               % Record peak 
               guess(i,2) = max(hand_vel);
               
               clearvars trajectory_shape virt_pos virt_vel hand_vel
               
            end
        
        end
        
        function value_ms = s_to_ms(app, value_s)

        % INPUT:
        % value_s: value in seconds 
        
        % OUTPUT:
        % value_ms: value in milliseconds 
        
        if length(value_s) > 1
            value_ms = value_s .* 1000;
        else 
            value_ms = value_s * 1000;
        end

        end
        
        function [x,y] = T1reachT2(app, T1, T2, duration)
        % INPUTS:
        % T1/T2: targets xy
        % duration: full time of reach (ms) (i.e. 750) 
        
        % OUTPUT:
        % xy: location to make a reach with bell shaped velocity profile 
        
        time = 1:1:duration;
        x_i = T1(1); x_f = T2(1);
        y_i = T1(2); y_f = T2(2); 
        
        % Calculations (from VirtualTrajectory Stateflow) 
        % sigmoid shape (i.e. derivative result in bell shape)
        trajectory_shape = time./duration-sin(2*pi.*time/duration)./(2*pi); 
        % positions 
        x = x_i + (x_f - x_i)* trajectory_shape;
        y = y_i + (y_f - y_i)* trajectory_shape;

        end
        
        function dx = derivative(~, x,N,dim)
        % DERIVATIVE Compute derivative while preserving dimensions.
        % 
        % DERIVATIVE(X), for a vector X, is an estimate of the first derivative of X.
        % DERIVATIVE(X), for a matrix X, is a matrix containing the first
        %   derivatives of the columns of X.
        % DERIVATIVE(X,N) is the Nth derivative along the columns of X.
        % DERIVATIVE(X,N,DIM) is the Nth derivative along dimension DIM of X. 
        % 
        % DERIVATIVE averages neighboring values of the simple finite differencing
        % method to obtain an estimate of the derivative that is exactly the same
        % size as X. This stands in contrast to Matlab's built-in DIFF, which, when
        % computing a derivative of order N on length M vectors, produces a vector
        % of length M-N. DERIVATIVE is therefore useful for estimating derivatives
        % at the same points over which X is defined, rather than in between
        % samples (as occurs implicity when using Matlab's DIFF). This means that,
        % for example, dX can be plotted against the same independent variables as
        % X. Note that the first and last elements of DERIVATIVE(X) will be the
        % same as those produced by DIFF(X).
        %
        % For N > 1, DERIVATIVE operates iteratively N times. If N = 0, DERIVATIVE
        % is the identity transformation. Use caution when computing derivatives
        % for N high relative to size(X,DIM). A warning will be issued.
        % 
        % Unless DIM is specified, DERIVATIVE computes the Nth derivative
        % along the columns of a matrix input.
        %
        % EXAMPLE: 
        % t = linspace(-4,4,500); x = normpdf(t); 
        % dx = derivative(x); dt = derivative(t); 
        % plot(t,x,t,dx./dt);
        % 
        % Created by Scott McKinney, October 2010
        %
        % See also GRADIENT
        
        %set DIM
        if nargin<4  
           if size(x,1)==1 %if row vector        
               dim = 2;
           else
               dim = 1; %default to computing along the columns, unless input is a row vector
           end
        else           
            if ~isscalar(dim) || ~ismember(dim,[1 2])    
                error('dim must be 1 or 2!')
            end
        end
        
        %set N
        if nargin<3 || isempty(N) %allows for letting N = [] as placeholder
            N = 1; %default to first derivative    
        else        
            if ~isscalar(N) || N~=round(N)
                error('N must be a scalar integer!')
            end
        end
        
        if size(x,dim)<=1 && N
            error('X cannot be singleton along dimension DIM')
        elseif N>=size(x,dim)
            warning('Computing derivative of order longer than or equal to size(x,dim). Results may not be valid...')
        end
        
        dx = x; %'Zeroth' derivative
        
        for n = 1:N % Apply iteratively
        
            dif = diff(dx,1,dim);
        
            if dim==1
                first = [dif(1,:) ; dif];
                last = [dif; dif(end,:)];
            elseif dim==2;
                first = [dif(:,1) dif];
                last = [dif dif(:,end)];
            end
            
            dx = (first+last)/2;
        end
        end
        
        function value_s = ms_to_s(app, value_ms)

        % INPUT:
        % value_ms: value in milliseconds 
        
        % OUTPUT:
        % value_s: value in seconds 
        
        if length(value_ms) > 1
            value_s = value_ms ./ 1000;
        else 
            value_s = value_ms / 1000;
        end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;  % clean up old errors 
            % initilize values 
            % end target
            app.EndTargetSpinner.Value = 2; 
            % response latency 
            app.Control_RL.Value = 446.50; 
            app.LA_RL.Value = 817.00;
            app.RA_RL.Value = 500.00;
            % peak speed ratio 
            app.Control_PSR.Value = 1.10;
            app.LA_PSR.Value = 1.02; 
            app.RA_PSR.Value = 1.11;
            % initial direction error 
            app.Control_IDE.Value = 14.80;
            app.LA_IDE.Value = 28.00;
            app.RA_IDE.Value = 22.109; 
            % path length ratio 
            app.Control_PLR.Value = 1.06; 
            app.LA_PLR.Value = 1.13; 
            app.RA_PLR.Value = 1.10; 
            
            % create targets 
            createTargs(app); 

        end

        % Value changed function: GoButton
        function GoButtonValueChanged(app, event)
            % check start and end are not the same 
            if app.StartTargetSpinner.Value == app.EndTargetSpinner.Value
                return
            end
            
            % set as off 
            app.GoButton.Enable = 0;
            
            % simulate data 
            createReaches(app); 
            
            % Run through reach 
            max_time = max([length(app.robot_vel), length(app.control_vel), length(app.LA_vel), length(app.RA_vel)]); 
            % visualize 
            for i = 1:25:max_time
                
                % ATBA hand (4)
                if i <= length(app.robX)
                    set(app.Position.Children(4), 'XData', app.robX(i));
                    set(app.Position.Children(4), 'YData', app.robY(i)); 
                end
                % nATBA hand 
                % control (3)
                if i <= length(app.controlX)
                    set(app.Position.Children(3), 'XData', -app.controlX(i));
                    set(app.Position.Children(3), 'YData', app.controlY(i)); 
                end
                % LA (2)
                if i <= length(app.LAX)
                    set(app.Position.Children(2), 'XData', -app.LAX(i));
                    set(app.Position.Children(2), 'YData', app.LAY(i)); 
                end
                % RA (1)
                if i <= length(app.RAX)
                    set(app.Position.Children(1), 'XData', -app.RAX(i));
                    set(app.Position.Children(1), 'YData', app.RAY(i)); 
                end
                
                % time slider 
                set(app.Speed.Children(1), 'XData', [i, i]); 
                set(app.Speed.Children(1), 'YData', app.Speed.YLim);
                
                pause(ms_to_s(app, 1)); 
                
            end
            % set as on 
            app.GoButton.BackgroundColor = 'g';
            app.GoButton.Enable = 1;
        end

        % Value changed function: EndTargetSpinner, 
        % StartTargetSpinner
        function StartTargetSpinnerValueChanged(app, event)
            start_value = app.StartTargetSpinner.Value;
            end_value = app.EndTargetSpinner.Value;
            if start_value == end_value
                app.GoButton.BackgroundColor = [0.97,0.67,0.22]; % orange-ish
            else 
                app.GoButton.BackgroundColor = 'g'; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartTargetSpinnerLabel = uilabel(app.UIFigure);
            app.StartTargetSpinnerLabel.Text = 'Start Target';
            app.StartTargetSpinner = uispinner(app.UIFigure);
            app.StartTargetSpinner.Limits = [1 3];
            app.StartTargetSpinner.ValueChangedFcn = createCallbackFcn(app, @StartTargetSpinnerValueChanged, true);
            app.StartTargetSpinner.Value = 1;
            app.EndTargetSpinnerLabel = uilabel(app.UIFigure);
            app.EndTargetSpinnerLabel.Text = 'End Target';
            app.EndTargetSpinner = uispinner(app.UIFigure);
            app.EndTargetSpinner.Limits = [1 3];
            app.EndTargetSpinner.ValueChangedFcn = createCallbackFcn(app, @StartTargetSpinnerValueChanged, true);
            app.EndTargetSpinner.Value = 1;
            app.GoButton = uibutton(app.UIFigure, 'state');
            app.GoButton.ValueChangedFcn = createCallbackFcn(app, @GoButtonValueChanged, true);
            app.GoButton.Text = 'Go!';
            app.ControlEditFieldLabel = uilabel(app.UIFigure);
            app.ControlEditFieldLabel.Text = 'Control';
            app.Control_RL = uieditfield(app.UIFigure, 'numeric');
            app.ResponseLatencyLabel = uilabel(app.UIFigure);
            app.ResponseLatencyLabel.Text = 'Response Latency';
            app.LALabel = uilabel(app.UIFigure);
            app.LALabel.Text = 'LA';
            app.LA_RL = uieditfield(app.UIFigure, 'numeric');
            app.RALabel = uilabel(app.UIFigure);
            app.RALabel.Text = 'RA';
            app.RA_RL = uieditfield(app.UIFigure, 'numeric');
            app.PeakSpeedRatioLabel = uilabel(app.UIFigure);
            app.PeakSpeedRatioLabel.Text = 'Peak Speed Ratio';
            app.ControlEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ControlEditFieldLabel_2.Text = 'Control';
            app.Control_PSR = uieditfield(app.UIFigure, 'numeric');
            app.LALabel_2 = uilabel(app.UIFigure);
            app.LALabel_2.Text = 'LA';
            app.LA_PSR = uieditfield(app.UIFigure, 'numeric');
            app.RALabel_2 = uilabel(app.UIFigure);
            app.RALabel_2.Text = 'RA';
            app.RA_PSR = uieditfield(app.UIFigure, 'numeric');
            app.InitialDirectionErrorLabel = uilabel(app.UIFigure);
            app.InitialDirectionErrorLabel.Text = 'Initial Direction Error';
            app.ControlEditFieldLabel_3 = uilabel(app.UIFigure);
            app.ControlEditFieldLabel_3.Text = 'Control';
            app.Control_IDE = uieditfield(app.UIFigure, 'numeric');
            app.LALabel_3 = uilabel(app.UIFigure);
            app.LALabel_3.Text = 'LA';
            app.LA_IDE = uieditfield(app.UIFigure, 'numeric');
            app.RALabel_3 = uilabel(app.UIFigure);
            app.RALabel_3.Text = 'RA';
            app.RA_IDE = uieditfield(app.UIFigure, 'numeric');
            app.PathLengthRatioLabel = uilabel(app.UIFigure);
            app.PathLengthRatioLabel.Text = 'Path Length Ratio';
            app.ControlEditFieldLabel_4 = uilabel(app.UIFigure);
            app.ControlEditFieldLabel_4.Text = 'Control';
            app.Control_PLR = uieditfield(app.UIFigure, 'numeric');
            app.LALabel_4 = uilabel(app.UIFigure);
            app.LALabel_4.Text = 'LA';
            app.LA_PLR = uieditfield(app.UIFigure, 'numeric');
            app.RALabel_4 = uilabel(app.UIFigure);
            app.RALabel_4.Text = 'RA';
            app.RA_PLR = uieditfield(app.UIFigure, 'numeric');
            app.Position = uiaxes(app.UIFigure);
            xlabel(app.Position, 'X (cm)')
            ylabel(app.Position, 'Y (cm)')
            zlabel(app.Position, 'Z')
            app.Speed = uiaxes(app.UIFigure);
            xlabel(app.Speed, 'Time (ms)')
            ylabel(app.Speed, 'Speed (m/s)')
            zlabel(app.Speed, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = customSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end