classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        RelationshipbetweenPDFandCDFPanel  matlab.ui.container.Panel
        MuSliderLabel     matlab.ui.control.Label
        MuSlider          matlab.ui.control.Slider
        SigmaSliderLabel  matlab.ui.control.Label
        SigmaSlider       matlab.ui.control.Slider
        PDF               matlab.ui.control.UIAxes
        CDF               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pd = makedist('normal', 'mu', 0.025, ...
                'sigma', 0.025);
            x = 0:0.01:1;
            pdf_norm = pdf(pd,x);
            plot(app.PDF, x, pdf_norm, '-')
            
            cdf_norm = cdf(pd, x);
            plot(app.CDF, x, cdf_norm, '-')
        end

        % Value changed function: MuSlider, SigmaSlider
        function MuSliderValueChanged(app, event)
            valueMu = app.MuSlider.Value;
            valueSigma = app.SigmaSlider.Value;
            
            pd = makedist('normal', 'mu', valueMu, ...
                'sigma', valueSigma);
            x = 0:0.01:1;
            pdf_norm = pdf(pd,x);
            plot(app.PDF, x, pdf_norm, '-')
            
            cdf_norm = cdf(pd, x);
            plot(app.CDF, x, cdf_norm, '-')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RelationshipbetweenPDFandCDFPanel = uipanel(app.UIFigure);
            app.RelationshipbetweenPDFandCDFPanel.Title = 'Relationship between PDF and CDF';
            app.MuSliderLabel = uilabel(app.RelationshipbetweenPDFandCDFPanel);
            app.MuSliderLabel.Text = 'Mu';
            app.MuSlider = uislider(app.RelationshipbetweenPDFandCDFPanel);
            app.MuSlider.Limits = [0.025 1];
            app.MuSlider.ValueChangedFcn = createCallbackFcn(app, @MuSliderValueChanged, true);
            app.MuSlider.Value = 0.025;
            app.SigmaSliderLabel = uilabel(app.RelationshipbetweenPDFandCDFPanel);
            app.SigmaSliderLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.RelationshipbetweenPDFandCDFPanel);
            app.SigmaSlider.Limits = [0.025 1];
            app.SigmaSlider.ValueChangedFcn = createCallbackFcn(app, @MuSliderValueChanged, true);
            app.SigmaSlider.Value = 0.025;
            app.PDF = uiaxes(app.RelationshipbetweenPDFandCDFPanel);
            title(app.PDF, 'Probability density function')
            xlabel(app.PDF, 'X')
            ylabel(app.PDF, 'Y')
            app.CDF = uiaxes(app.RelationshipbetweenPDFandCDFPanel);
            title(app.CDF, 'Cumulative distribution function')
            xlabel(app.CDF, 'X')
            ylabel(app.CDF, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end