classdef fourP_psycho_fxn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        alphaSliderLabel   matlab.ui.control.Label
        alphaSlider        matlab.ui.control.Slider
        betaSliderLabel    matlab.ui.control.Label
        betaSlider         matlab.ui.control.Slider
        lambdaSliderLabel  matlab.ui.control.Label
        lambdaSlider       matlab.ui.control.Slider
        RerandomizeButton  matlab.ui.control.Button
        gammaSliderLabel   matlab.ui.control.Label
        gammaSlider        matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % 4 parameter psychometric function with logistic function as nested function 
        psi = @(x,xdata) x(4) + (1 - x(3) - x(4)) * (1 ./ (1 + exp(-x(2) * (xdata - x(1))))); % alpha, beta, lambda, gamma % Description
        intensity = -0.4:0.01:0.4; % x-values 
        params % parameters 
        gamma_opts = [0, 0.25, 0.33, 0.5];
    end
    
    methods (Access = private)
        
        function rnd_num = rnd_btw(~, min, max)
            % INPUT:
            % min: minimum value 
            % max: maximum value 
            
            % OUTPUT:
            % rnd_num: random number between min and max 
            
            rnd_num = min + (max-min) * rand(1);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % ylimit 
            app.UIAxes.YLim = [0,1];
            % Generate some data 
            % generate parameters
            app.params = [0, 25, 0, 0];
            % set values accordingly 
            app.alphaSlider.Value = app.params(1); 
            app.betaSlider.Value = app.params(2);
            app.lambdaSlider.Value = app.params(3);
            app.gammaSlider.Value = app.params(4);
            % create and plot function 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Button pushed function: RerandomizeButton
        function RerandomizeButtonPushed(app, event)
            % generate parameters
            app.params = [app.rnd_btw(-0.4, 0.4), app.rnd_btw(6,40), app.rnd_btw(0,0.5), app.gamma_opts(randi(4))];
            % set values accordingly 
            app.alphaSlider.Value = app.params(1); 
            app.betaSlider.Value = app.params(2);
            app.lambdaSlider.Value = app.params(3);
            app.gammaSlider.Value = app.params(4);
            % create and plot function 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Value changing function: lambdaSlider
        function lambdaSliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameters
            app.params(3) = changingValue; 
            % replot 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Value changing function: betaSlider
        function betaSliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameters
            app.params(2) = changingValue; 
            % replot 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Value changing function: alphaSlider
        function alphaSliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameters
            app.params(1) = changingValue; 
            % replot 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
            changingValue = event.Value;
            % update parameters
            app.params(4) = changingValue; 
            % replot 
            plot(app.UIAxes, app.intensity, app.psi(app.params, app.intensity), 'linewidth', 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [-0.4 0.4];
            app.alphaSlider.ValueChangingFcn = createCallbackFcn(app, @alphaSliderValueChanging, true);
            app.betaSliderLabel = uilabel(app.UIFigure);
            app.betaSliderLabel.Text = 'beta';
            app.betaSlider = uislider(app.UIFigure);
            app.betaSlider.Limits = [6 40];
            app.betaSlider.ValueChangingFcn = createCallbackFcn(app, @betaSliderValueChanging, true);
            app.betaSlider.Value = 6;
            app.lambdaSliderLabel = uilabel(app.UIFigure);
            app.lambdaSliderLabel.Text = 'lambda';
            app.lambdaSlider = uislider(app.UIFigure);
            app.lambdaSlider.Limits = [0 0.5];
            app.lambdaSlider.ValueChangingFcn = createCallbackFcn(app, @lambdaSliderValueChanging, true);
            app.RerandomizeButton = uibutton(app.UIFigure, 'push');
            app.RerandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @RerandomizeButtonPushed, true);
            app.RerandomizeButton.Text = 'Re-randomize';
            app.gammaSliderLabel = uilabel(app.UIFigure);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.UIFigure);
            app.gammaSlider.Limits = [0 0.5];
            app.gammaSlider.MajorTicks = [0 0.25 0.33 0.5];
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.gammaSlider.MinorTicks = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Psychometric function')
            xlabel(app.UIAxes, 'Stimulus')
            ylabel(app.UIAxes, 'Probability correct')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fourP_psycho_fxn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end